classdef visualizeVolume < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        xslicePanel            matlab.ui.container.Panel
        xslider                matlab.ui.control.Slider
        xlogscaleCheckBox      matlab.ui.control.CheckBox
        yslicePanel            matlab.ui.container.Panel
        yslider                matlab.ui.control.Slider
        ylogscaleCheckBox      matlab.ui.control.CheckBox
        zslicePanel            matlab.ui.container.Panel
        zslider                matlab.ui.control.Slider
        zlogscaleCheckBox      matlab.ui.control.CheckBox
        coloraxesPanel         matlab.ui.container.Panel
        colormapDropDownLabel  matlab.ui.control.Label
        colormapDropDown       matlab.ui.control.DropDown
        caxisMax               matlab.ui.control.Slider
        caxisMin               matlab.ui.control.Slider
        caxislogCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        V
        xtick
        ytick
        ztick
        xRange
        yRange
        zRange
        xLogMin
        xLogRange
        yLogMin
        yLogRange
        zLogMin
        zLogRange
        globalColorAxis
        currentColorAxis
    end
    
    methods (Access = private)
        
        function printSlice(app)
            % X Position
            if ~app.xlogscaleCheckBox.Value
                xval = app.xtick(1) + app.xRange*app.xslider.Value;
            else
                xLogVal = app.xLogMin + app.xLogRange*app.xslider.Value;
                xval = 10^xLogVal;
            end
            % Y Position
            if ~app.ylogscaleCheckBox.Value
                yval = app.ytick(1) + app.yRange*app.yslider.Value;
            else
                yLogVal = app.yLogMin + app.yLogRange*app.yslider.Value;
                yval = 10^yLogVal;
            end
            % Z Position
            if ~app.zlogscaleCheckBox.Value
                zval = app.ztick(1) + app.zRange*app.zslider.Value;
            else
                zLogVal = app.zLogMin + app.zLogRange*app.zslider.Value;
                zval = 10^zLogVal;
            end
            slice(app.UIAxes,app.xtick,app.ytick,app.ztick,app.V,xval,yval,zval);
            caxis(app.UIAxes,app.currentColorAxis);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, V, xtick, ytick, ztick, names)
            app.UIFigure.Visible = 'off';
            
            if ndims(V)~=3, error('Requires V to be a 3-D Array'); end
            d = size(V);
            if length(xtick)~=d(2), error('xtick must have same length as size(V,2)'); end
            if length(ytick)~=d(1), error('ytick must have same length as size(V,1)'); end
            if length(ztick)~=d(3), error('ztick must have same length as size(V,3)'); end
            
            app.V = V;
            app.xtick = xtick;
            app.ytick = ytick;
            app.ztick = ztick;
            
            app.globalColorAxis = [min(V(:)) max(V(:))];
            app.currentColorAxis = [min(V(:)) max(V(:))];
            
            app.xRange = diff(xtick([1 end]));
            app.yRange = diff(ytick([1 end]));
            app.zRange = diff(ztick([1 end]));
            
            xLogBounds = [log10(app.xtick(1)) log10(app.xtick(end))];
            yLogBounds = [log10(app.ytick(1)) log10(app.ytick(end))];
            zLogBounds = [log10(app.ztick(1)) log10(app.ztick(end))];
            app.xLogMin = xLogBounds(1);
            app.yLogMin = yLogBounds(1);
            app.zLogMin = zLogBounds(1);
            app.xLogRange = diff(xLogBounds);
            app.yLogRange = diff(yLogBounds);
            app.zLogRange = diff(zLogBounds);
            
            printSlice(app);
            colormapDropDownValueChanged(app)
            
            xlim(app.UIAxes,[min(xtick) max(xtick)]);
            ylim(app.UIAxes,[min(ytick) max(ytick)]);
            zlim(app.UIAxes,[min(ztick) max(ztick)]);
            
            if (nargin==6)
                xlabel(app.UIAxes, names{1});
                ylabel(app.UIAxes, names{2});
                zlabel(app.UIAxes, names{3});
            end
            
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: xslider, yslider, zslider
        function sliderChanged(app, event)
            printSlice(app);
        end

        % Value changing function: xslider
        function xsliderValueChanging(app, event)
            changingValue = event.Value;
            app.xslider.Value = changingValue;
            printSlice(app);
        end

        % Value changing function: yslider
        function ysliderValueChanging(app, event)
            changingValue = event.Value;
            app.yslider.Value = changingValue;
            printSlice(app);
        end

        % Value changing function: zslider
        function zsliderValueChanging(app, event)
            changingValue = event.Value;
            app.zslider.Value = changingValue;
            printSlice(app);
        end

        % Value changed function: xlogscaleCheckBox, 
        % ylogscaleCheckBox, zlogscaleCheckBox
        function logscaleSwitch(app, event)
            set(app.UIAxes,'xscale',convertValueToScale(app.xlogscaleCheckBox.Value));
            set(app.UIAxes,'yscale',convertValueToScale(app.ylogscaleCheckBox.Value));
            set(app.UIAxes,'zscale',convertValueToScale(app.zlogscaleCheckBox.Value));
            xlim(app.UIAxes,[app.xtick(1) app.xtick(end)]);
            ylim(app.UIAxes,[app.ytick(1) app.ytick(end)]);
            zlim(app.UIAxes,[app.ztick(1) app.ztick(end)]);
            function scaleType = convertValueToScale(value)
                if value
                    scaleType = 'log';
                else
                    scaleType = 'linear';
                end
            end
            printSlice(app);
        end

        % Value changed function: colormapDropDown
        function colormapDropDownValueChanged(app, event)
            if app.caxisMin.Value < app.caxisMax.Value
                colormap(app.UIAxes,app.colormapDropDown.Value);
            else
                cmap = eval(app.colormapDropDown.Value);
                colormap(app.UIAxes,flipud(cmap));
            end
        end

        % Callback function: caxisMin, caxisMin
        function caxisMinValueChanging(app, event)
            changingValue = event.Value;
            if changingValue == app.caxisMax.Value
                changingValue = app.caxisMax.Value-0.01;
            end
            newMinimum = app.globalColorAxis(1) + changingValue*diff(app.globalColorAxis);
            oldMaximum = app.globalColorAxis(1) + app.caxisMax.Value*diff(app.globalColorAxis);
            newAxis = [newMinimum oldMaximum];
            app.currentColorAxis = sort(newAxis);
            caxis(app.UIAxes,app.currentColorAxis);
            colormapDropDownValueChanged(app);
        end

        % Value changing function: caxisMax
        function caxisMaxValueChanging(app, event)
            changingValue = event.Value;
            if changingValue == app.caxisMin.Value
                changingValue = app.caxisMin.Value+0.01;
            end
            newMaximum = app.globalColorAxis(1) + changingValue*diff(app.globalColorAxis);
            oldMinimum = app.globalColorAxis(1) + app.caxisMin.Value*diff(app.globalColorAxis);
            newAxis = [oldMinimum newMaximum];
            app.currentColorAxis = sort(newAxis);
            caxis(app.UIAxes,app.currentColorAxis);
            colormapDropDownValueChanged(app);
        end

        % Value changed function: caxislogCheckBox
        function caxislogCheckBoxValueChanged(app, event)
            value = app.caxislogCheckBox.Value;
            if value
                set(app.UIAxes,'colorscale','log');
            else
                set(app.UIAxes,'colorscale','linear');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.xslicePanel = uipanel(app.UIFigure);
            app.xslicePanel.Title = 'x slice';
            app.xslider = uislider(app.xslicePanel);
            app.xslider.Limits = [0 1];
            app.xslider.MajorTicks = [];
            app.xslider.MajorTickLabels = {''};
            app.xslider.ValueChangedFcn = createCallbackFcn(app, @sliderChanged, true);
            app.xslider.ValueChangingFcn = createCallbackFcn(app, @xsliderValueChanging, true);
            app.xslider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.xslider.Value = 1;
            app.xlogscaleCheckBox = uicheckbox(app.xslicePanel);
            app.xlogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @logscaleSwitch, true);
            app.xlogscaleCheckBox.Text = 'x log scale';
            app.yslicePanel = uipanel(app.UIFigure);
            app.yslicePanel.Title = 'y slice';
            app.yslider = uislider(app.yslicePanel);
            app.yslider.Limits = [0 1];
            app.yslider.MajorTicks = [];
            app.yslider.MajorTickLabels = {''};
            app.yslider.ValueChangedFcn = createCallbackFcn(app, @sliderChanged, true);
            app.yslider.ValueChangingFcn = createCallbackFcn(app, @ysliderValueChanging, true);
            app.yslider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.yslider.Value = 1;
            app.ylogscaleCheckBox = uicheckbox(app.yslicePanel);
            app.ylogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @logscaleSwitch, true);
            app.ylogscaleCheckBox.Text = 'y log scale';
            app.zslicePanel = uipanel(app.UIFigure);
            app.zslicePanel.Title = 'z slice';
            app.zslider = uislider(app.zslicePanel);
            app.zslider.Limits = [0 1];
            app.zslider.MajorTicks = [];
            app.zslider.MajorTickLabels = {''};
            app.zslider.ValueChangedFcn = createCallbackFcn(app, @sliderChanged, true);
            app.zslider.ValueChangingFcn = createCallbackFcn(app, @zsliderValueChanging, true);
            app.zslider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.zlogscaleCheckBox = uicheckbox(app.zslicePanel);
            app.zlogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @logscaleSwitch, true);
            app.zlogscaleCheckBox.Text = 'z log scale';
            app.coloraxesPanel = uipanel(app.UIFigure);
            app.coloraxesPanel.Title = 'color axes';
            app.colormapDropDownLabel = uilabel(app.coloraxesPanel);
            app.colormapDropDownLabel.Text = 'colormap:';
            app.colormapDropDown = uidropdown(app.coloraxesPanel);
            app.colormapDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'hot';
            app.caxisMin = uislider(app.coloraxesPanel);
            app.caxisMin.Limits = [0 1];
            app.caxisMin.MajorTicks = [];
            app.caxisMin.ValueChangedFcn = createCallbackFcn(app, @caxisMinValueChanging, true);
            app.caxisMin.ValueChangingFcn = createCallbackFcn(app, @caxisMinValueChanging, true);
            app.caxisMax = uislider(app.coloraxesPanel);
            app.caxisMax.Limits = [0 1];
            app.caxisMax.MajorTicks = [];
            app.caxisMax.ValueChangingFcn = createCallbackFcn(app, @caxisMaxValueChanging, true);
            app.caxisMax.MinorTicks = [];
            app.caxisMax.Value = 1;
            app.caxislogCheckBox = uicheckbox(app.coloraxesPanel);
            app.caxislogCheckBox.ValueChangedFcn = createCallbackFcn(app, @caxislogCheckBoxValueChanged, true);
            app.caxislogCheckBox.Text = 'caxis log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualizeVolume(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end